classdef CovidVisualizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CovidVisualizationAppUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        CountryListBoxLabel            matlab.ui.control.Label
        CountryListBox                 matlab.ui.control.ListBox
        StateListBoxLabel              matlab.ui.control.Label
        StateListBox                   matlab.ui.control.ListBox
        MovingAverageSpinnerLabel      matlab.ui.control.Label
        MovingAverageSpinner           matlab.ui.control.Spinner
        OptionsSwitchLabel             matlab.ui.control.Label
        OptionsSwitch                  matlab.ui.control.Switch
        PlotKnobLabel                  matlab.ui.control.Label
        PlotKnob                       matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        Cases  % Database with all the new cases
        Deaths  % Database with all the new deaths
        Dates  % Cell of dates 
        CountriesAndStates % Cell of countries and States        
        PlaceCases  % Cases of the selected place
        PlaceDeaths  % Deaths of the selected place
        
    end
    
    methods (Access = private)
        
        function plotData(app)
            % Getting usefull information
            country = app.CountryListBox.Value;
            state = app.StateListBox.Value;
            opt = app.OptionsSwitch.Value;
            knopf = app.PlotKnob.Value;            
            num_date = datenum(app.Dates);
            avg = app.MovingAverageSpinner.Value;
            
            % Retrieving the data
            da_cases = app.PlaceCases;
            da_deaths = app.PlaceDeaths;               
            
            % Reseting the graph
            cla(app.UIAxes, 'reset')
            
            % Editting the y and x labels 
            grid(app.UIAxes, 'on')
            xlim(app.UIAxes, [num_date(1) num_date(end)])
            datetick(app.UIAxes, 'x', 'dd/mm')  
                        
            if knopf == "Both"                   
                
                % Changing the title        
                if opt == "Culmulative" 
                    full_title = sprintf('All cases and deaths of COVID-19 for %s (considering %s region and %i-day mean)', country, state, avg);
                else  % i.e., opt == "Daily"
                    full_title = sprintf('New cases and deaths of COVID-19 for %s (considering %s region and %i-day mean)', country, state, avg);
                end
               
                title(app.UIAxes, full_title) 
                
                % Editing the Y axis and ploting the data
                yyaxis(app.UIAxes, 'left')
                ylabel(app.UIAxes, 'New cases')
                ylim(app.UIAxes, [0, max(cell2mat(da_cases))])
                yticks(app.UIAxes, linspace(min(cell2mat(da_cases)), max(cell2mat(da_cases)), 10))
                ytickformat(app.UIAxes, '%d')
                app.UIAxes.YAxis(1).Exponent = 0;
                bar(app.UIAxes, datenum(app.Dates), cell2mat(da_cases), 'b')
                
                yyaxis(app.UIAxes, 'right')
                ylabel(app.UIAxes, 'New deaths')
                ylim(app.UIAxes, [0, max(cell2mat(da_deaths))])
                ytickformat(app.UIAxes, '%d')
                app.UIAxes.YAxis(2).Exponent = 0;
                plot(app.UIAxes, datenum(app.Dates), cell2mat(da_deaths), 'r')                                                                      
               
                return
                
            elseif knopf == "Cases"
                
                % Changing the title        
                if opt == "Culmulative" 
                    full_title = sprintf('All cases of COVID-19 for %s (considering %s region and %i-day mean)', country, state, avg);
                else  % i.e., opt == "Daily"
                    full_title = sprintf('New cases of COVID-19 for %s (considering %s region and %i-day mean)', country, state, avg);
                end
                
                title(app.UIAxes, full_title)
                
                % Editing the left Y axis ploting only the New Cases data
                ylabel(app.UIAxes, 'New cases')
                ylim(app.UIAxes, [0, max(cell2mat(da_cases))])
                yticks(app.UIAxes, linspace(min(cell2mat(da_cases)), max(cell2mat(da_cases)), 10))
                ytickformat(app.UIAxes, '%d')
                app.UIAxes.YAxis.Exponent = 0;
                bar(app.UIAxes, datenum(app.Dates), cell2mat(da_cases), 'b')
                
                return
                
            else  % i.e. knopf == "Deaths"
                   
                % Changing the title        
                if opt == "Culmulative"
                    full_title = sprintf('All deaths of COVID-19 for %s (considering %s region and %i-day mean)', country, state, avg);
                else  % i.e., opt == "Daily"
                    full_title = sprintf('New deaths of COVID-19 for %s (considering %s region and %i-day mean)', country, state, avg);
                end
                
                title(app.UIAxes, full_title)
                
                % Editing the rigth Y axis and ploting  only the New Deaths data
                ylabel(app.UIAxes, 'New deaths')
                ylim(app.UIAxes, [0, max(cell2mat(da_deaths))])
                ytickformat(app.UIAxes, '%d')
                app.UIAxes.YAxis.Exponent = 0;
                plot(app.UIAxes, datenum(app.Dates), cell2mat(da_deaths), 'r')
                
                return
                
            end                         
        end
        
        function changeData(app)
            % Getting usefull information
            country = app.CountryListBox.Value;
            state = app.StateListBox.Value;
            swt = app.OptionsSwitch.Value;
            avg = app.MovingAverageSpinner.Value;
            knob = app.PlotKnob.Value;
            
            if knob == "Both"
                % Selecting the New Cases database for the given country and state
                da_cases = app.Cases(strcmp(app.Cases(:, 1), country),  2:end);
                da_cases = da_cases(strcmp(da_cases(:, 1), state), 2:end);
            
                % Selecting the New Deaths database for the given country and state
                da_deaths = app.Deaths(strcmp(app.Deaths(:, 1), country),  2:end);
                da_deaths = da_deaths(strcmp(da_deaths(:, 1), state), 2:end);
                
                % If the switch is in the "Daily" position, we need to edit the database
                if swt == "Daily"
                    for ii = length(app.Dates):-1:2  
                        da_cases{ii} = da_cases{ii} - da_cases{ii - 1};
                        da_deaths{ii} = da_deaths{ii} - da_deaths{ii - 1};
                    end
                end
                
                da_cases = mat2cell(movmean(cell2mat(da_cases), avg), 1);
                da_deaths = mat2cell(movmean(cell2mat(da_deaths), avg), 1);
                
                app.PlaceCases = da_cases;
                app.PlaceDeaths = da_deaths;
                
                return
                
            elseif knob == "Cases"
                % Selecting the New Cases database for the given country and state
                da_cases = app.Cases(strcmp(app.Cases(:, 1), country),  2:end);
                da_cases = da_cases(strcmp(da_cases(:, 1), state), 2:end);
                
                % If the switch is in the "Daily" position, we need to edit the database
                if swt == "Daily"
                    for ii = length(app.Dates):-1:2  
                        da_cases{ii} = da_cases{ii} - da_cases{ii - 1};
                    end
                end
                
                da_cases = mat2cell(movmean(cell2mat(da_cases), avg), 1);
                
                app.PlaceCases = da_cases;
                
                return
                
            else  % i.e., knob == "Deaths"
                % Selecting the New Deaths database for the given country and state
                da_deaths = app.Deaths(strcmp(app.Deaths(:, 1), country),  2:end);
                da_deaths = da_deaths(strcmp(da_deaths(:, 1), state), 2:end);
                
                % If the switch is in the "Daily" position, we need to edit the database
                if swt == "Daily"
                    for ii = length(app.Dates):-1:2  
                        da_deaths{ii} = da_deaths{ii} - da_deaths{ii - 1};
                    end
                end
                    
                da_deaths = mat2cell(movmean(cell2mat(da_deaths), avg), 1);
                
                app.PlaceDeaths = da_deaths;
                
                return
                
                
            end          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            
            da = covid_data;  % Usable data go from 2:end, to 3:end
            
            % Modifying to add 'Global':
            [rows, columns] = size(da);
            new_line = ['Global', 'All', cell(1, columns-2)];
            da = [da(1, :); new_line; da(2:end, :)];
            
            % Usefull relations:
            values = da(1, :);
            countries_and_states = da(2:end, 1:2);
            
            % Preallocating Memory:
            app.Cases = cell(rows + 1, columns);  % The + 1 to consider the Global (added before)
            app.Deaths = cell(rows + 1, columns);
            
            % Adding the values, countries and states to the other databases
            app.Cases(1, :) = values;
            app.Deaths(1, :) = values;
            app.Cases(2:end, 1:2) = countries_and_states;
            app.Deaths(2:end, 1:2) = countries_and_states;
            
            % Separating the databases and calculating the global values
            for jj = 3:columns
                acum_case = 0;  % Acumulator --> Will be used to calculate the global cases and deaths
                acum_deaths = 0;
                for ii = 3:rows
                    acum_case = acum_case + da{ii, jj}(1);
                    acum_deaths = acum_deaths + da{ii, jj}(2);
                    
                    app.Cases{ii, jj} = da{ii, jj}(1);
                    app.Deaths{ii, jj} = da{ii, jj}(2);
                end
                app.Cases{2, jj} = acum_case;
                app.Deaths{2, jj} = acum_deaths;
            end
            
            % Modifying to use 'All' instead of []
            da(strcmp(da(:, 2), ""), 2) = {'All'};
            app.Cases(strcmp(app.Cases(:, 2), ""), 2) = {'All'};
            app.Deaths(strcmp(app.Deaths(:, 2), ""), 2) = {'All'};
            
            % Setting the properties
            app.Dates = da(1, 3:end);
            app.CountriesAndStates = da(2:end, 1:2);
       
            % Editing the lists
            app.CountryListBox.Items = unique(da(2:end, 1), 'stable');
            app.CountryListBox.Value = 'Global';
            app.StateListBox.Items = {'All'};  % This will be changed latter
            app.StateListBox.Value = 'All';
            
            % Using'Global' and 'All' as the standard values:
            da_cases = app.Cases(strcmp(app.Cases(:, 1), 'Global'),  2:end);
            da_cases = da_cases(strcmp(da_cases(:, 1), 'All'), 2:end);
            
            da_deaths = app.Deaths(strcmp(app.Deaths(:, 1), 'Global'),  2:end);
            da_deaths = da_deaths(strcmp(da_deaths(:, 1), 'All'), 2:end);
            
            app.PlaceCases = da_cases;
            app.PlaceDeaths = da_deaths;
            
            app.plotData()
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            country = app.CountryListBox.Value;
            std_state = 'All';
            
            app.StateListBox.Items = app.CountriesAndStates(strcmp(app.CountriesAndStates(:, 1), country), 2);
            app.StateListBox.Value = std_state;
                        
            app.changeData()          
            
            app.plotData()
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            app.changeData()
            
            app.plotData()
        end

        % Value changed function: OptionsSwitch
        function OptionsSwitchValueChanged(app, event)
            app.changeData()
            
            app.plotData()
        end

        % Value changed function: PlotKnob
        function PlotKnobValueChanged(app, event)
            app.changeData()
            
            app.plotData()
        end

        % Value changed function: MovingAverageSpinner
        function MovingAverageSpinnerValueChanged(app, event)
            app.changeData()
            
            app.plotData()            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CovidVisualizationAppUIFigure = uifigure('Visible', 'off');
            app.CovidVisualizationAppUIFigure.Name = 'Covid Visualization App';
            app.UIAxes = uiaxes(app.CovidVisualizationAppUIFigure);
            title(app.UIAxes, 'Loading...')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CountryListBoxLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.CovidVisualizationAppUIFigure);
            app.CountryListBox.Items = {'Loading...', ''};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateListBoxLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.StateListBoxLabel.Text = 'State';
            app.StateListBox = uilistbox(app.CovidVisualizationAppUIFigure);
            app.StateListBox.Items = {'Loading...', ''};
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.StateListBox.Value = {};
            app.MovingAverageSpinnerLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.MovingAverageSpinnerLabel.Text = 'Moving Average';
            app.MovingAverageSpinner = uispinner(app.CovidVisualizationAppUIFigure);
            app.MovingAverageSpinner.Limits = [1 15];
            app.MovingAverageSpinner.RoundFractionalValues = 'on';
            app.MovingAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @MovingAverageSpinnerValueChanged, true);
            app.MovingAverageSpinner.Value = 1;
            app.OptionsSwitchLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.OptionsSwitchLabel.Text = 'Options';
            app.OptionsSwitch = uiswitch(app.CovidVisualizationAppUIFigure, 'slider');
            app.OptionsSwitch.Items = {'Culmulative', 'Daily'};
            app.OptionsSwitch.ValueChangedFcn = createCallbackFcn(app, @OptionsSwitchValueChanged, true);
            app.OptionsSwitch.Value = 'Culmulative';
            app.PlotKnobLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.PlotKnobLabel.Text = 'Plot';
            app.PlotKnob = uiknob(app.CovidVisualizationAppUIFigure, 'discrete');
            app.PlotKnob.Items = {'Cases', 'Both', 'Deaths'};
            app.PlotKnob.ValueChangedFcn = createCallbackFcn(app, @PlotKnobValueChanged, true);
            app.PlotKnob.Value = 'Both';
            % Show the figure after all components are created
            app.CovidVisualizationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidVisualizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CovidVisualizationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CovidVisualizationAppUIFigure)
        end
    end
end